classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        cartel_entrada  matlab.ui.control.Label
        placa_parking   matlab.ui.control.Label
        date            matlab.ui.control.Label
        estado_barrera  matlab.ui.control.Label
        button          matlab.ui.control.Button
        numCoches       matlab.ui.control.Label
    end

    
    properties (Access = private)
        s % Serial variable
        t % Timer Variable
    end  
    
    methods (Access = private)
        
        function my_callback_fcn(app,src,event)
            global max numCoches
            reloj = clock;
            DateVector = [reloj(1),reloj(2),reloj(3),reloj(4),reloj(5),reloj(6)];
            Date = datestr(DateVector, 'HH:MM:SS dd/mm/yy');
            app.date.Text = Date; 
            
            writeline(app.s,"2");
            textoRecibido = readline(app.s);
            if(~isempty(textoRecibido))
                if(num2str(textoRecibido) == "4")
                    app.estado_barrera.Text = "BARRERA CERRADA";
                end
                if(num2str(textoRecibido) == "3")
                    numCoches = numCoches - 1;
                    app.numCoches.Text = num2str(max-numCoches)
                end
            end
        end
        
        function t = createTimer(app)
            t = timer;
            t.StartDelay = 1;
            t.ExecutionMode = 'fixedSpacing';
            t.TimerFcn = @app.my_callback_fcn;
            t.Period = 1.5;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            delete(instrfind({'port'},{'COM4'}));
            app.s = serialport("COM4",19200,"Timeout",3);  
            configureTerminator(app.s, "CR/LF", "CR");
            
            global  max numCoches
            
            max = 200;
            numCoches = 0;
                        
            app.estado_barrera.Text = "BARRERA CERRADA";
            app.numCoches.Text = num2str(max-numCoches);
            
            app.t = createTimer(app);
            start(app.t);
            
        end

        % Button pushed function: button
        function buttonButtonPushed(app, event)
            global max numCoches    
            
            flush(app.s,"input");
            writeline(app.s,"0");            
            textoRecibido = readline(app.s);
            if(~isempty(textoRecibido))
                if(textoRecibido == "1")      
                    %Al recibir '1' se indica que si se detecta un coche en el
                    %sensor
                    
                    app.estado_barrera.Text = "Solcitud Pendiente ...";
                    pause(1);
                    writeline(app.s, "1");  
                        %Solicitud a Arduino para que le indique si hay sitio o no
                        %en el parking
                    textoRecibido = readline(app.s);
                    if(~isempty(textoRecibido))
                        if(textoRecibido == "2")
                            numCoches = numCoches + 1;
                            app.estado_barrera.Text = "Solictud Aceptada";
                            app.numCoches.Text = num2str(max-numCoches);
                        else
                            app.estado_barrera.Text = "Solicitud Denegada";
                        end   
                    end
                end
            else
                app.estado_barrera.Text = "Entrada Denegada. No hay coche"  
            end
            
            flush(app.s,"input");
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.t);
            delete(app.t);
            delete(app);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.cartel_entrada = uilabel(app.UIFigure);
            app.cartel_entrada.Text = {'ENTRADA PARKING'; 'UNIVERSIDAD'};
            app.placa_parking = uilabel(app.UIFigure);
            app.placa_parking.Text = 'P';
            app.date = uilabel(app.UIFigure);
            app.estado_barrera = uilabel(app.UIFigure);
            app.button = uibutton(app.UIFigure, 'push');
            app.button.ButtonPushedFcn = createCallbackFcn(app, @buttonButtonPushed, true);
            app.button.Text = '';
            app.numCoches = uilabel(app.UIFigure);
            app.numCoches.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end